classdef ParticleAnalizer_v1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ImageAnalyze             matlab.ui.container.TabGroup
        L                        matlab.ui.container.Tab
        Load                     matlab.ui.control.StateButton
        FigureL                  matlab.ui.control.UIAxes
        FigList                  matlab.ui.control.ListBox
        SetscaleButton           matlab.ui.control.Button
        pixelLabel               matlab.ui.control.Label
        Pixel                    matlab.ui.control.NumericEditField
        umLabel                  matlab.ui.control.Label
        um                       matlab.ui.control.NumericEditField
        Lamp0                    matlab.ui.control.Lamp
        Update                   matlab.ui.control.Button
        TextArea_5               matlab.ui.control.TextArea
        En                       matlab.ui.container.Tab
        FigureE                  matlab.ui.control.UIAxes
        Panel                    matlab.ui.container.Panel
        BinValue                 matlab.ui.control.Spinner
        Binarize                 matlab.ui.control.Button
        Lamp1                    matlab.ui.control.Lamp
        Panel_2                  matlab.ui.container.Panel
        OpenValue                matlab.ui.control.Spinner
        Open                     matlab.ui.control.Button
        Lamp2                    matlab.ui.control.Lamp
        ComplementSwitchLabel    matlab.ui.control.Label
        Comp                     matlab.ui.control.Switch
        SizeSpinnerLabel         matlab.ui.control.Label
        DiskSize                 matlab.ui.control.Spinner
        DiskStrel                matlab.ui.control.Button
        TextArea_6               matlab.ui.control.TextArea
        M                        matlab.ui.container.Tab
        Gen                      matlab.ui.control.Button
        FigureM                  matlab.ui.control.UIAxes
        ParticleSpinnerLabel     matlab.ui.control.Label
        Par                      matlab.ui.control.Spinner
        ParticleaspetratioLabel  matlab.ui.control.Label
        Ratio                    matlab.ui.control.Spinner
        FigureI                  matlab.ui.control.UIAxes
        Lamp3                    matlab.ui.control.Lamp
        add                      matlab.ui.control.Button
        exclude                  matlab.ui.control.Button
        MeasureparticlesPanel    matlab.ui.container.Panel
        Measure                  matlab.ui.control.Button
        MeasureALL               matlab.ui.control.Button
        Clear                    matlab.ui.control.Button
        ClearALL                 matlab.ui.control.Button
        Lamp4                    matlab.ui.control.Lamp
        RefineparticlesPanel     matlab.ui.container.Panel
        ShapeLabel               matlab.ui.control.Label
        StrelType                matlab.ui.control.DropDown
        WidthLabel               matlab.ui.control.Label
        wStrel                   matlab.ui.control.Spinner
        LengthLabel              matlab.ui.control.Label
        lStrel                   matlab.ui.control.Spinner
        Strel                    matlab.ui.control.Button
        StrelALL                 matlab.ui.control.Button
        Lamp5                    matlab.ui.control.Lamp
        TextArea_7               matlab.ui.control.TextArea
        R                        matlab.ui.container.Tab
        Report                   matlab.ui.control.Table
        Export                   matlab.ui.control.Button
        TablenameLabel           matlab.ui.control.Label
        csvName                  matlab.ui.control.EditField
        TextArea_8               matlab.ui.control.TextArea
        InfoTab                  matlab.ui.container.Tab
        Image                    matlab.ui.control.Image
        TextArea_10              matlab.ui.control.TextArea
        ProgramName              matlab.ui.control.Label
        AddfeaturePanel          matlab.ui.container.Panel
        FigureAdd                matlab.ui.control.UIAxes
        draw                     matlab.ui.control.Button
        widthEditFieldLabel      matlab.ui.control.Label
        Pwidth                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        
        listing % file contained in the folder (.../Figure)
        list = ([]); % list of figure names
        FigName % name of the figure
        sComp = 0 % complement status
        se % stel characteristic
        x % index of selected bwRef
        n = 0; % measure number
        h % draggable line
        scale % scala
        RR % rotated rectangles
        Tdata % data
        
        I % original image
        bw % binarized image
        bwOpen % openned image
        bwRef % refined image
        bwCropped % selected cropped bwRef image
        bwRotCr % rotated bwCropped images
        bwRCRef % refined bwRotCr images
        ICropped % selected cropped I image
        IRotCr % rotated ICropped images
        
        
        bwStats % properties of bwRef
        selStats % properties of selected bwRef
        rotStats % dimension of particle
        mS % status of measurement
        mLast % # particle of the last measurement
        rS % status of final refinement
        rSALL % status for refine group particles
        
        color % color of bounding boxes
       
           
    end
    
    methods (Access = private)


        function finRefine(app)
            
            % strel open with selected strel shape
            app.bwRCRef{app.x} = imopen(app.bwRotCr{app.x}, app.se);
            imshow(app.bwRCRef{app.x}, 'Parent', app.FigureI);
            pause (0.1);
            
            % recalculate region properties
            a = 0;
            j = 1;
            Tstats = regionprops(app.bwRCRef{app.x},...
                'MajorAxisLength', 'MinorAxisLength', 'Orientation', 'BoundingBox', 'Centroid', 'Area');
            
            if isempty(Tstats) == 1
                text(5, 10,'Change param',...
                    'Parent', app.FigureI,...
                    'FontSize', 18,...
                    'Color', 'r',...
                    'FontWeight', 'Bold',...
                    'HorizontalAlignment', 'left');
                app.color = 'r';
                app.upStats;
                app.mS(app.x) = 2;
            else
 
                while a == 0
                    r = rectangle ('Position', Tstats(j).BoundingBox, 'EdgeColor', 'b' , 'Parent', app.FigureI);
                    if app.selStats(app.x).MajorAxisLength < (Tstats(j).MajorAxisLength*1.25)
                        a = 1;
                        % update stats of rotated particle 
                        app.rotStats(app.x) = Tstats(j);
                        app.rS(app.x) = 1;
                        app.rSALL(app.x) = 0;
                        app.color = 'c';
                        app.upStats;
                    else
                        j = j+1;
                        pause (0.1);
                        delete(r);
                        if length(Tstats) < j
                            text(5, 10,'Change param',...
                                'Parent', app.FigureI,...
                                'FontSize', 18,...
                                'Color', 'r',...
                                'FontWeight', 'Bold',...
                                'HorizontalAlignment', 'left');
                            a = 1;
                            app.color = 'r';
                            app.upStats;
                            app.mS(app.x) = 2;
                            app.ParValueChanged;  
                        end
                    end
                end     
            end
        end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function upStats(app)
            
            %update the selection rectangle in the original image
            delete(app.RR{app.x});
            if app.color ~= 'r'
                % draw rectangle
                pos = [-app.rotStats(app.x).BoundingBox(3)/2, -app.rotStats(app.x).BoundingBox(4)/2,...
                    app.rotStats(app.x).BoundingBox(3), app.rotStats(app.x).BoundingBox(4)];
                g = hgtransform('Parent',app.FigureM);
                g.Matrix = makehgtform('translate',[app.selStats(app.x).Centroid, 0])*...
                    makehgtform('zrotate', -deg2rad(app.selStats(app.x).Orientation+app.rotStats(app.x).Orientation));
                app.RR{app.x} = rectangle('Position', pos,'EdgeColor',app.color,'Parent',g);
            end
            
        end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function printStat(app)
            
            % print particle statistics in a new line of the table
            app.n = app.n+1;
            if app.mS(app.x) == 0
                if app.rS(app.x) == 0
                    data = [app.n,...
                        app.rotStats(app.x).MinorAxisLength*app.scale,...
                        app.rotStats(app.x).MajorAxisLength*app.scale,...
                        app.rotStats(app.x).Area*app.scale*app.scale];
                    app.Report.Data = [app.Report.Data; data];
                elseif app.rS(app.x) == 1
                    data = [app.n,...
                        app.rotStats(app.x).BoundingBox(4)*app.scale,...
                        app.rotStats(app.x).BoundingBox(3)*app.scale,...
                        app.rotStats(app.x).Area*app.scale*app.scale];
                    app.Report.Data = [app.Report.Data; data];
                end
                app.mS(app.x) = 1;
                app.mLast = app.x;
            elseif app.mS(app.x) == 1
                text(5, 10,'Yet measured',...
                    'Parent', app.FigureI,...
                    'FontSize', 18,...
                    'Color', 'r',...
                    'FontWeight', 'Bold',...
                    'HorizontalAlignment', 'left');
            end
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function StypeS(app)
            
            switch app.StrelType.Value
                case 'diamond'
                    app.lStrel.Enable = 1;
                    app.wStrel.Enable = 0;
                    app.lStrel.Limits = [0 inf];
                    app.lStrel.Step = 1;
                case 'disk'
                    app.lStrel.Enable = 1;
                    app.wStrel.Enable = 0;
                    app.lStrel.Limits = [0 inf];
                    app.lStrel.Step = 1;
                case 'line'
                    app.lStrel.Enable = 1;
                    app.wStrel.Enable = 1;
                    app.lStrel.Limits = [1 inf];
                    app.lStrel.Step = 1;
                case 'octagon'
                    app.lStrel.Enable = 1;
                    app.wStrel.Enable = 0;
                    app.lStrel.Limits = [0 inf];
                    app.lStrel.Step = 3;
                case 'rectangle'
                    app.lStrel.Enable = 1;
                    app.wStrel.Enable = 1;
                    app.lStrel.Limits = [1 inf];
                    app.lStrel.Step = 1;
                    app.wStrel.Limits = [1 inf];
                    app.wStrel.Step = 1;
                case 'square'
                    app.lStrel.Enable = 1;
                    app.wStrel.Enable = 0;
                    app.lStrel.Limits = [1 inf];
                    app.lStrel.Step = 1;
            end
            
        end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function seSet(app)
            
            %create se shape for strel open
            switch app.StrelType.Value
                case 'diamond'
                    app.se = strel('diamond', app.lStrel.Value); %P5
                case 'disk'
                    app.se = strel('disk', app.lStrel.Value); %P5
                case 'line'
                    app.se = strel('line', app.lStrel.Value, 0); %P5
                case 'octagon'
                    app.se = strel('octagon', app.lStrel.Value); %P5
                case 'rectangle'
                    mn = [app.wStrel.Value app.lStrel.Value];
                    app.se = strel('rectangle', mn); %P5
                case 'square'
                    app.se = strel('square', app.lStrel.Value); %P5
            end
        end
    
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % STARTUP conditioning
            app.listing = dir('Figure/');
            app.list = [];
            for k = 1 : length(app.listing)
                app.list{k} = app.listing(k).name;
            end
            app.FigList.Items = app.list;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Value changed function: Load
        function LoadValueChanged(app, event)
            
            % loading of a new image and set of figure title
            app.FigureL.Title.String = app.FigList.Value;
            app.FigureE.Title.String = app.FigList.Value;
            app.FigureM.Title.String = app.FigList.Value;
            app.FigName = strcat('Figure/',app.FigList.Value);
            app.I = imread(app.FigName);
            k = size(app.I, 3);
            if k == 3
                app.I = rgb2gray(app.I);
            end
            imshow(app.I, 'Parent', app.FigureL);
            imshow(app.I, 'Parent', app.FigureE);
            imshow(app.I, 'Parent', app.FigureM);
            
            % inizialization of image and stats containers and lamp indicators
            app.bw = [];
            app.bwOpen = [];
            app.bwRef = [];
            app.bwCropped = [];
            app.bwRotCr = ([]);
            app.bwRCRef = ([]);
            app.ICropped = [];
            app.IRotCr = ([]);
            
            app.bwStats = 0;
            app.selStats = 0;
            app.rotStats = 0;
            app.RR = ([]);
            app.mS = 0;
            app.rS = 0;
            app.rSALL = 1;
            app.scale = 0;
            
            app.Lamp0.Color = 'r';
            app.Lamp1.Color = 'r';
            app.Lamp2.Color = 'r';
            app.Lamp3.Color = 'r';
            app.Lamp4.Color = 'r';
            app.Lamp5.Color = 'r';
            
            
            % open figure dialog for calibration
    
            app.h = drawline('Parent',app.FigureL);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Button pushed function: Binarize
        function BinarizeButtonPushed(app, event)
            
            % Binarize image and set lamp1 to green
            app.bw = imbinarize(app.I, app.BinValue.Value); %P1
            imshow(app.bw, 'Parent', app.FigureE);
            app.Lamp1.Color = 'g';
            app.Lamp2.Color = 'r';
            app.Lamp3.Color = 'r';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Button pushed function: Open
        function OpenButtonPushed(app, event)
    
            % open and fill image bw, store bwOpen and create bwRef, set lamp2 to green
            app.bwOpen = bwareaopen(app.bw,app.OpenValue.Value); %P2
            app.bwOpen = imfill (app.bwOpen, 'holes');
            app.bwRef = app.bwOpen;
            imshow(app.bwRef, 'Parent', app.FigureE);
            app.Lamp2.Color = 'g';
            app.Lamp3.Color = 'r';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Value changed function: Comp
        function CompValueChanged(app, event)

            % complement image bw and set lamp2 to red
            app.bw = imcomplement (app.bw);
            imshow(app.bw, 'Parent', app.FigureE);
            app.Lamp2.Color = 'r';
            app.Lamp3.Color = 'r';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Button pushed function: DiskStrel
        function DiskStrelButtonPushed(app, event)
            
            % open bwRef with disk strel
            app.se = strel('disk', app.DiskSize.Value); %P3
            app.bwRef = imopen(app.bwOpen, app.se);
            imshow(app.bwRef, 'Parent', app.FigureE);
            app.Lamp3.Color = 'r';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Button pushed function: Gen
        function GenButtonPushed(app, event)
 
            % isolate particle
            app.bwStats = regionprops(app.bwRef,...
                'MajorAxisLength', 'MinorAxisLength', 'Orientation', 'BoundingBox', 'Centroid', 'Area');
            
            % inizialize stats containers
            app.selStats = app.bwStats(1);
            app.rotStats = app.bwStats(1);
            for k = 1 : length(app.RR)
                delete(app.RR{k});
            end
            
            
            z = 0;
            for k = 1:length(app.bwStats)
                q = app.bwStats(k).MajorAxisLength/app.bwStats(k).MinorAxisLength;
                if q >= app.Ratio.Value %P4
                    z = z+1;
                    app.bwCropped = imcrop(app.bwOpen, app.bwStats(k).BoundingBox);
                    app.bwRotCr{z} = imrotate(app.bwCropped,-app.bwStats(k).Orientation);
                    app.ICropped = imcrop(app.I, app.bwStats(k).BoundingBox);
                    app.IRotCr{z} = imrotate(app.ICropped,-app.bwStats(k).Orientation);
                    app.bwRCRef{z} = app.bwRotCr{z};
                    app.selStats(z) = app.bwStats(k);
                    app.rotStats(z) = app.selStats(z);
                    
                    % draw rectangle
                    pos = [-app.bwStats(k).MajorAxisLength/2, -app.bwStats(k).MinorAxisLength/2,...
                        app.bwStats(k).MajorAxisLength, app.bwStats(k).MinorAxisLength];
                    g = hgtransform('Parent',app.FigureM);
                    g.Matrix = makehgtform('translate',[app.bwStats(k).Centroid, 0])*...
                        makehgtform('zrotate', -deg2rad(app.bwStats(k).Orientation));
                    app.RR{z} = rectangle('Position', pos,'EdgeColor','b','Parent',g);
                end
            end
            
            % inizialize status containers
            app.mS = zeros(1,length(app.selStats));
            app.rS = app.mS;
            app.rSALL = app.mS+1;

            % show indexed figure
            imshow(app.bwRotCr{1}, 'Parent', app.FigureI);
            z = length(app.selStats);
            if z == 1
                app.Par.Enable = 0;
            elseif z > 1
                app.Par.Enable = 1;
                app.Par.Limits = [1 z];
                app.Par.Value = 1;
            end
            app.x = 1;
            app.Lamp3.Color = 'g';
            app.ParValueChanged;
            app.Lamp5.Color = 'r';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Value changed function: Par
        function ParValueChanged(app, event)
            
            % show # isolated particle
            switch app.mS(app.x)
                case 0
                    app.color = 'b';
                    if app.rS(app.x) == 1
                        app.color = 'c';
                    end
                        
                case 1
                    app.color = 'g';
                        
                case 2
                    app.color = 'r';
                        
            end
            app.upStats;
                
            app.x = app.Par.Value;
            if app.rS(app.x) == 1
                imshow(app.IRotCr{app.x}, 'Parent', app.FigureI);
                switch app.mS(app.x)
                    case 0
                        app.color = 'c';
                        text(5, 10,'To be measured',...
                         'Parent', app.FigureI,...
                         'FontSize', 12,...
                         'Color', 'r',...
                         'FontWeight', 'Bold',...
                         'HorizontalAlignment', 'left');
                    case 1
                        app.color = 'g';
                        text(5, 10,'Yet measured',...
                         'Parent', app.FigureI,...
                         'FontSize', 12,...
                         'Color', 'r',...
                         'FontWeight', 'Bold',...
                         'HorizontalAlignment', 'left');
                    case 2
                        app.color = 'r';
                        text(5, 10,'Excluded',...
                         'Parent', app.FigureI,...
                         'FontSize', 12,...
                         'Color', 'r',...
                         'FontWeight', 'Bold',...
                         'HorizontalAlignment', 'left');
                end
                rectangle ('Position', app.rotStats(app.x).BoundingBox, 'EdgeColor', app.color , 'Parent', app.FigureI);
            elseif app.rS(app.x) == 0
                imshow(app.bwRCRef{app.x}, 'Parent', app.FigureI);
                switch app.mS(app.x)
                    case 0
                        text(5, 10,'To be measured',...
                         'Parent', app.FigureI,...
                         'FontSize', 12,...
                         'Color', 'r',...
                         'FontWeight', 'Bold',...
                         'HorizontalAlignment', 'left');
                    case 1
                        text(5, 10,'Yet measured',...
                         'Parent', app.FigureI,...
                         'FontSize', 12,...
                         'Color', 'r',...
                         'FontWeight', 'Bold',...
                         'HorizontalAlignment', 'left');
                    case 2
                        text(5, 10,'Excluded',...
                         'Parent', app.FigureI,...
                         'FontSize', 12,...
                         'Color', 'r',...
                         'FontWeight', 'Bold',...
                         'HorizontalAlignment', 'left');
                end
            end
            
            app.color = 'y';
            app.upStats;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK

            
        end

        % Button pushed function: Strel
        function StrelButtonPushed(app, event)
            
            % for the selected particle refine bwCRref with indicated strel and update stats
            switch app.mS(app.x)
                case 0
                    app.seSet;
                    app.finRefine;
                case 1
                    text(5, 10,'Yet measured',...
                         'Parent', app.FigureI,...
                         'FontSize', 18,...
                         'Color', 'r',...
                         'FontWeight', 'Bold',...
                         'HorizontalAlignment', 'left');
                case 2
                    text(5, 10,'Excluded particle',...
                         'Parent', app.FigureI,...
                         'FontSize', 18,...
                         'Color', 'r',...
                         'FontWeight', 'Bold',...
                         'HorizontalAlignment', 'left');
            end
            app.ParValueChanged;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Button pushed function: Measure
        function MeasureButtonPushed(app, event)

            % print selected particle statistics in a new line of the table
            if app.mS(app.x) == 0
                app.printStat;
                app.color = 'g';
                app.upStats;
            end
            app.ParValueChanged;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Button pushed function: SetscaleButton
        function SetscaleButtonPushed2(app, event)
           
           % set scale of the loaded image
           app.Pixel.Value = ((app.h.Position(2)-app.h.Position(1))^2+(app.h.Position(4)-app.h.Position(3))^2)^(1/2);
           app.scale = app.um.Value/app.Pixel.Value;
           app.Lamp0.Color = 'g';
           
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
           
        end

        % Button pushed function: StrelALL
        function StrelALLButtonPushed(app, event)
            app.Lamp5.Color = 'r';
            % refine ALL bwCRref with indicated strel and update stats
            for k = 1 : length(app.selStats)
                app.x = k;
                if (app.rSALL(app.x) == 1) && (app.mS(app.x) == 0)
                    app.lStrel.Value = 1;
                    app.seSet;
                    app.finRefine;
                    while app.rotStats(app.x).BoundingBox(4)*app.rotStats(app.x).BoundingBox(3) >= app.rotStats(app.x).Area*1.25
                        app.lStrel.Value = app.lStrel.Value+1;
                        app.seSet;
                        app.finRefine;
                        if app.mS(app.x) == 2 % condition is used to exit while function if no feature is present
                            break
                        end
                    end
                end
                pause (0.1);
            end
            app.ParValueChanged;
            app.Lamp5.Color = 'g';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Button pushed function: MeasureALL
        function MeasureALLButtonPushed(app, event)
            app.Lamp4.Color = 'r';
            % print selected particle statistics in a new line of the table
            for k = 1 : length(app.selStats)
                app.x = k;
                if app.mS(app.x) == 0
                    app.color = 'g';
                    app.upStats;
                    app.printStat;
                end
            end
            app.ParValueChanged;
            app.Lamp4.Color = 'g';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            
            % clear last measurement and update status indicator
            
            app.x = app.mLast;
            if app.rS(app.x) == 1
                app.color = 'c';
            else
                app.color = 'b';
            end
            app.upStats;
            app.Report.Data(app.n, :) = [];
            app.n = app.n -1;
            app.mS(app.mLast) = 0;
            app.ParValueChanged;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
            
        end

        % Button pushed function: ClearALL
        function ClearALLButtonPushed(app, event)
            
            % clear all measurement done
            app.Report.Data = [];
            app.mS = zeros(1,length(app.selStats));
            for k = 1 : length(app.selStats)
                app.x = k;
                if app.rS(app.x) == 1
                    app.color = 'c';
                else
                    app.color = 'b';
                end
                app.upStats;
            end
            app.ParValueChanged;
            app.n = 0;
            app.mS(app.mLast) = 0;
            app.Lamp4.Color = 'r';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
            
        end

        % Button pushed function: Update
        function UpdateButtonPushed(app, event)
            
            app.startupFcn;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Value changed function: StrelType
        function StrelTypeValueChanged(app, event)
            
            % setup strel type dimensions imput
            app.StypeS;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)
            
            %export data
            app.Tdata = app.Report.Data;
            file = strcat('Figure/',strcat(app.csvName.Value,'.csv'));
            xlswrite (file, app.Tdata);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Button pushed function: draw
        function drawParticle(app, event)

           z = length(app.selStats);
           z = z+1;

           a = app.h.Position(2)-app.h.Position(1);
           b = app.h.Position(4)-app.h.Position(3);
           c = (a^2+b^2)^(1/2);
           if app.h.Position(1) > app.h.Position(2)
               center1 = app.h.Position(2)+abs(a/2);
           else
               center1 = app.h.Position(1)+abs(a/2);
           end
           if app.h.Position(3) > app.h.Position(4)
               center2 = app.h.Position(4)+abs(b/2);
           else
               center2 = app.h.Position(3)+abs(b/2);
           end
           if abs(a) > abs(b)
               pos = [center1-abs(a) center2-abs(a) abs(2*a) abs(2*a)];
           else
               pos = [center1-abs(b) center2-abs(b) abs(2*b) abs(2*b)];
           end
           
           angle = atand(a/b)+90;
           
           app.selStats(z) = struct('MajorAxisLength', c,...
                                    'MinorAxisLength', app.Pwidth.Value,...
                                    'Orientation', angle,...
                                    'BoundingBox', pos,...
                                    'Centroid', [center1 center2],...
                                    'Area', a*b);
           app.AddfeaturePanel.Visible = 'off';
           app.rotStats(z) = app.selStats(z);
           
           app.bwCropped = imcrop(app.bw, app.selStats(z).BoundingBox); % qui prima c'era app.bwOpen
           app.bwRotCr{z} = imrotate(app.bwCropped,-app.selStats(z).Orientation);
           app.ICropped = imcrop(app.I, app.selStats(z).BoundingBox);
           app.IRotCr{z} = imrotate(app.ICropped,-app.selStats(z).Orientation);
           app.bwRCRef{z} = app.bwRotCr{z};
                    
           pos = [-c/2, -app.Pwidth.Value/2,...
                  c, app.Pwidth.Value];
           g = hgtransform('Parent',app.FigureM);
           g.Matrix = makehgtform('translate',[[center1 center2], 0])*...
                      makehgtform('zrotate', -deg2rad(angle));
           app.RR{z} = rectangle('Position', pos,'EdgeColor','b','Parent',g);
           
           % inizialize status containers
            app.mS(z) = 0;
            app.rS(z) = 0;
            app.rSALL(z) = 1;

            % show indexed figure
            imshow(app.bwRotCr{z}, 'Parent', app.FigureI);
            app.Par.Limits = [1 z];
            app.x = z;
            app.Par.Value = z;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
            
        end

        % Button pushed function: add
        function addParticle(app, event)
            % open figure dialog for adding particle 
            app.AddfeaturePanel.Visible = 'on';
            imshow(app.I, 'Parent', app.FigureAdd);
            
            app.h = drawline('Parent', app.FigureAdd);
       
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
        end

        % Button pushed function: exclude
        function excludeButtonPushed(app, event)
            % exclude particle or include it
            switch app.mS(app.x)
                case 0
                    app.color = 'r';
                    app.upStats;
                    app.mS(app.x) = 2;
                case 1
                    text(5, 10,'Yet measured',...
                         'Parent', app.FigureI,...
                         'FontSize', 18,...
                         'Color', 'r',...
                         'FontWeight', 'Bold',...
                         'HorizontalAlignment', 'left');
                case 2
                    app.color = 'b';
                    app.upStats;
                    app.mS(app.x) = 0;
            end
            app.ParValueChanged;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OK
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImageAnalyze = uitabgroup(app.UIFigure);
            app.L = uitab(app.ImageAnalyze);
            app.L.Title = 'Load';
            app.Load = uibutton(app.L, 'state');
            app.Load.ValueChangedFcn = createCallbackFcn(app, @LoadValueChanged, true);
            app.Load.Text = 'Load file';
            app.FigureL = uiaxes(app.L);
            title(app.FigureL, 'Title')
            app.FigureL.XTick = [];
            app.FigureL.YTick = [];
            app.FigList = uilistbox(app.L);
            app.SetscaleButton = uibutton(app.L, 'push');
            app.SetscaleButton.ButtonPushedFcn = createCallbackFcn(app, @SetscaleButtonPushed2, true);
            app.SetscaleButton.Text = 'Set scale';
            app.pixelLabel = uilabel(app.L);
            app.pixelLabel.Text = 'pixel';
            app.Pixel = uieditfield(app.L, 'numeric');
            app.Pixel.Editable = 'off';
            app.umLabel = uilabel(app.L);
            app.umLabel.Text = 'um';
            app.um = uieditfield(app.L, 'numeric');
            app.Lamp0 = uilamp(app.L);
            app.Update = uibutton(app.L, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.Update.Text = 'Update list';
            app.TextArea_5 = uitextarea(app.L);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Software licensed under a GNU General Public License 3.0 (http://www.gnu.org/licenses/)'};
            app.En = uitab(app.ImageAnalyze);
            app.En.Title = 'Enhance';
            app.FigureE = uiaxes(app.En);
            title(app.FigureE, 'Title')
            app.FigureE.XTick = [];
            app.FigureE.YTick = [];
            app.Panel = uipanel(app.En);
            app.BinValue = uispinner(app.Panel);
            app.BinValue.Step = 0.05;
            app.BinValue.Limits = [0 1];
            app.BinValue.Value = 0.2;
            app.Binarize = uibutton(app.Panel, 'push');
            app.Binarize.ButtonPushedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.Binarize.Text = 'Binarize';
            app.Lamp1 = uilamp(app.Panel);
            app.Panel_2 = uipanel(app.En);
            app.OpenValue = uispinner(app.Panel_2);
            app.OpenValue.Step = 5;
            app.OpenValue.Limits = [0 Inf];
            app.OpenValue.Value = 100;
            app.Open = uibutton(app.Panel_2, 'push');
            app.Open.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.Open.Text = 'Open';
            app.Lamp2 = uilamp(app.Panel_2);
            app.ComplementSwitchLabel = uilabel(app.En);
            app.ComplementSwitchLabel.Text = 'Complement';
            app.Comp = uiswitch(app.En, 'slider');
            app.Comp.ValueChangedFcn = createCallbackFcn(app, @CompValueChanged, true);
            app.SizeSpinnerLabel = uilabel(app.En);
            app.SizeSpinnerLabel.Text = 'Size';
            app.DiskSize = uispinner(app.En);
            app.DiskSize.Limits = [0 100];
            app.DiskSize.Value = 1;
            app.DiskStrel = uibutton(app.En, 'push');
            app.DiskStrel.ButtonPushedFcn = createCallbackFcn(app, @DiskStrelButtonPushed, true);
            app.DiskStrel.Text = 'Refine';
            app.TextArea_6 = uitextarea(app.En);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'Software licensed under a GNU General Public License 3.0 (http://www.gnu.org/licenses/)'};
            app.M = uitab(app.ImageAnalyze);
            app.M.Title = 'Measure';
            app.Gen = uibutton(app.M, 'push');
            app.Gen.ButtonPushedFcn = createCallbackFcn(app, @GenButtonPushed, true);
            app.Gen.Text = 'Isolate particles';
            app.FigureM = uiaxes(app.M);
            title(app.FigureM, 'Title')
            app.FigureM.XTick = [];
            app.FigureM.YTick = [];
            app.ParticleSpinnerLabel = uilabel(app.M);
            app.ParticleSpinnerLabel.Text = 'Particle #';
            app.Par = uispinner(app.M);
            app.Par.Limits = [1 3];
            app.Par.ValueChangedFcn = createCallbackFcn(app, @ParValueChanged, true);
            app.Par.Value = 1;
            app.ParticleaspetratioLabel = uilabel(app.M);
            app.ParticleaspetratioLabel.Text = 'Particle aspet ratio';
            app.Ratio = uispinner(app.M);
            app.Ratio.Limits = [1 Inf];
            app.Ratio.Value = 4;
            app.FigureI = uiaxes(app.M);
            app.FigureI.XTick = [];
            app.FigureI.YTick = [];
            app.Lamp3 = uilamp(app.M);
            app.add = uibutton(app.M, 'push');
            app.add.ButtonPushedFcn = createCallbackFcn(app, @addParticle, true);
            app.add.Text = 'add particle';
            app.exclude = uibutton(app.M, 'push');
            app.exclude.ButtonPushedFcn = createCallbackFcn(app, @excludeButtonPushed, true);
            app.exclude.Text = 'exclude/include';
            app.MeasureparticlesPanel = uipanel(app.M);
            app.MeasureparticlesPanel.Title = 'Measure particles';
            app.Measure = uibutton(app.MeasureparticlesPanel, 'push');
            app.Measure.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.Measure.Text = 'Measure';
            app.MeasureALL = uibutton(app.MeasureparticlesPanel, 'push');
            app.MeasureALL.ButtonPushedFcn = createCallbackFcn(app, @MeasureALLButtonPushed, true);
            app.MeasureALL.Text = 'MeasureALL';
            app.Clear = uibutton(app.MeasureparticlesPanel, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'Clear last';
            app.ClearALL = uibutton(app.MeasureparticlesPanel, 'push');
            app.ClearALL.ButtonPushedFcn = createCallbackFcn(app, @ClearALLButtonPushed, true);
            app.ClearALL.Text = 'ClearALL';
            app.Lamp4 = uilamp(app.MeasureparticlesPanel);
            app.RefineparticlesPanel = uipanel(app.M);
            app.RefineparticlesPanel.Title = 'Refine particles';
            app.ShapeLabel = uilabel(app.RefineparticlesPanel);
            app.ShapeLabel.Text = 'Shape';
            app.StrelType = uidropdown(app.RefineparticlesPanel);
            app.StrelType.Items = {'diamond', 'disk', 'line', 'octagon', 'rectangle', 'square'};
            app.StrelType.ValueChangedFcn = createCallbackFcn(app, @StrelTypeValueChanged, true);
            app.StrelType.Value = 'diamond';
            app.WidthLabel = uilabel(app.RefineparticlesPanel);
            app.WidthLabel.Text = 'Width';
            app.wStrel = uispinner(app.RefineparticlesPanel);
            app.wStrel.Limits = [1 Inf];
            app.wStrel.Value = 4;
            app.LengthLabel = uilabel(app.RefineparticlesPanel);
            app.LengthLabel.Text = 'Length';
            app.lStrel = uispinner(app.RefineparticlesPanel);
            app.lStrel.Limits = [1 Inf];
            app.lStrel.Value = 4;
            app.Strel = uibutton(app.RefineparticlesPanel, 'push');
            app.Strel.ButtonPushedFcn = createCallbackFcn(app, @StrelButtonPushed, true);
            app.Strel.Text = 'Refine';
            app.StrelALL = uibutton(app.RefineparticlesPanel, 'push');
            app.StrelALL.ButtonPushedFcn = createCallbackFcn(app, @StrelALLButtonPushed, true);
            app.StrelALL.Text = 'RefineALL';
            app.Lamp5 = uilamp(app.RefineparticlesPanel);
            app.TextArea_7 = uitextarea(app.M);
            app.TextArea_7.Editable = 'off';
            app.TextArea_7.Value = {'Software licensed under a GNU General Public License 3.0 (http://www.gnu.org/licenses/)'};
            app.R = uitab(app.ImageAnalyze);
            app.R.Title = 'Report';
            app.Report = uitable(app.R);
            app.Report.ColumnName = {'Particle #'; 'Width [um]'; 'Length [um]'; 'Area [um^2]'};
            app.Report.RowName = {};
            app.Export = uibutton(app.R, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Text = 'Export';
            app.TablenameLabel = uilabel(app.R);
            app.TablenameLabel.Text = 'Table name';
            app.csvName = uieditfield(app.R, 'text');
            app.csvName.Value = 'test';
            app.TextArea_8 = uitextarea(app.R);
            app.TextArea_8.Editable = 'off';
            app.TextArea_8.Value = {'Software licensed under a GNU General Public License 3.0 (http://www.gnu.org/licenses/)'};
            app.InfoTab = uitab(app.ImageAnalyze);
            app.InfoTab.Title = 'Info';
            app.Image = uiimage(app.InfoTab);
            app.Image.ImageSource = 'FiberEUse_Logo.png';
            app.TextArea_10 = uitextarea(app.InfoTab);
            app.TextArea_10.Editable = 'off';
            app.TextArea_10.Value = {'Particle analizer - version 1.0'; ''; 'This software was developed in order to measure recycled fibers length and diameter. Nevertheless, it can be used for different kind of particles. Developed for the FiberEUse project: Large Scale Demonstration of New Circular Economy Value-chains based on the Reuse of End-of-life reinforced Composites (project’s Grant Agreement No 730323).'; '_________________________________________________________________________________________________________'; ''; 'Copyright (C) 2020  Andrea Mantelli, Department of Chemistry, Materials and Chemical Engineering "Giulio Natta", Politecnico di Milano, Piazza Leonardo da Vinci 32, 20133 Milano, Italy'; ''; 'This program is free software: you can redistribute it and/or modify it under the terms of the GNU General Public License as published by the Free Software Foundation, either version 3 of the License, or (at your option) any later version.'; ''; 'This program is distributed in the hope that it will be useful, but WITHOUT ANY WARRANTY; without even the implied warranty of MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU General Public License for more details.'; ''; 'You should have received a copy of the GNU General Public License along with this program.'; 'If not, see http://www.gnu.org/licenses/.'};
            app.ProgramName = uilabel(app.UIFigure);
            app.ProgramName.Text = '       Particle Analyzer  v1.0';
            app.AddfeaturePanel = uipanel(app.UIFigure);
            app.AddfeaturePanel.Title = '  Add feature';
            app.AddfeaturePanel.Visible = 'off';
            app.FigureAdd = uiaxes(app.AddfeaturePanel);
            title(app.FigureAdd, '')
            app.FigureAdd.XTick = [];
            app.FigureAdd.YTick = [];
            app.draw = uibutton(app.AddfeaturePanel, 'push');
            app.draw.ButtonPushedFcn = createCallbackFcn(app, @drawParticle, true);
            app.draw.Text = 'draw particle';
            app.widthEditFieldLabel = uilabel(app.AddfeaturePanel);
            app.widthEditFieldLabel.Text = 'width';
            app.Pwidth = uieditfield(app.AddfeaturePanel, 'numeric');
            app.Pwidth.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParticleAnalizer_v1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end